classdef sepa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EyesWindow             matlab.ui.container.Panel
        GoBackButtonEyes       matlab.ui.control.Button
        ExpandedPlot           matlab.ui.control.UIAxes
        AnalisisWindow         matlab.ui.container.Panel
        FatigaLabel            matlab.ui.control.Label
        PatientInfoLabel       matlab.ui.control.Label
        FATIGALabel            matlab.ui.control.Label
        DATOSDELPACIENTELabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        ANLISISPACIENTELabel   matlab.ui.control.Label
        RellenarButton         matlab.ui.control.Button
        UITableResultados      matlab.ui.control.Table
        GoBackButtonAnalisis   matlab.ui.control.Button
        Subplots               matlab.ui.container.Panel
        RestButton             matlab.ui.control.Button
        TestButton             matlab.ui.control.Button
        IntroButton            matlab.ui.control.Button
        AnalisisButton         matlab.ui.control.Button
        PruebasDropDown        matlab.ui.control.DropDown
        PruebasDropDownLabel   matlab.ui.control.Label
        rest                   matlab.ui.control.UIAxes
        test                   matlab.ui.control.UIAxes
        intro                  matlab.ui.control.UIAxes
        Mainmenu               matlab.ui.container.Panel
        DataButton             matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        CargardatosButton      matlab.ui.control.Button
        Mainplot               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        blocks
        Introplot  matlab.graphics.chart.primitive.Line
        Testplot   matlab.graphics.chart.primitive.Line
        Restplot   matlab.graphics.chart.primitive.Line
        index      double

        % >>> Info del paciente <<<
        PatientID string
        PatientGender string
        PatientAge double
        PatientParalysis string
        PatientNIHSS double
        PatientMBI double
        PatientmRS double

        % Tabla con los datos del TSV
        ParticipantsTable table
    end

    methods (Access = private)

        function ExpandedView(app,id)
            pos = app.Subplots.Position;
            app.Subplots.Visible = 'off';
            app.EyesWindow.Position = pos;
            app.EyesWindow.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);

            if (id == 0)
                app.ExpandedPlot.Title.String = "Introduction (0-2s)";
                plot(app.ExpandedPlot,app.Introplot.XData,app.Introplot.YData)
            elseif (id == 1)
                app.ExpandedPlot.Title.String = "Motor Imagery Test (2-6s)";
                plot(app.ExpandedPlot,app.Testplot.XData,app.Testplot.YData)
            elseif (id == 2)
                app.ExpandedPlot.Title.String = "Rest (6-8s)";
                plot(app.ExpandedPlot,app.Restplot.XData,app.Restplot.YData)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Panel1 visible, Panel2 oculto
            app.Mainmenu.Visible = 'on';
            app.Subplots.Visible = 'off';
            app.AnalisisWindow.Visible = "off";
            app.EyesWindow.Visible = 'off';

            % Ajustar posición del panel a [0,0] dentro de UIFigure
            app.Mainmenu.Position(1:2) = [0 0];

            % Ajustar tamaño y color de la UIFigure al panel
            app.UIFigure.Position(3:4) = app.Mainmenu.Position(3:4);
            ApplyTheme(app);
            % Cargar tabla de participantes
            if isfile("participants.tsv")
                app.ParticipantsTable = readtable("participants.tsv", 'FileType', 'text', 'Delimiter', '\t');
            else
                warning("No se encontró participants.tsv en la carpeta de la app.");
                app.ParticipantsTable = table(); % tabla vacía
            end
        end

        % Button pushed function: CargardatosButton
        function LoadData(app, event)
            % Diálogo para seleccionar archivo .mat
            [file, path] = uigetfile('*.edf', 'Seleccione un archivo edf');

            % Si el usuario cancela
            if isequal(file, 0)
                disp('El usuario canceló la operación.');
            else
                % Crea la ruta completa del archivo
                fullFileName = fullfile(path, file);

                % Carga el archivo
                data = edfread(fullFileName);

                % Extrae nombre y extensión del archivo
                [~, fileName, ext] = fileparts(fullFileName);

                % Muestra el nombre en un campo de texto del app
                app.Label.Text = [fileName, ext];

                tsvPath = fullfile(path, 'participants.tsv');

                if isfile(tsvPath)
                    app.ParticipantsTable = readtable(tsvPath, 'FileType','text','Delimiter','\t');
                else
                    warning("No se encontró participants.tsv en: " + path);
                    app.ParticipantsTable = table();
                end

                % ---- 2. Extraer ID del archivo (ej: 'sub-01') ----
                tokens = regexp(fileName, '(sub-\d+)', 'match');
                if ~isempty(tokens)
                    app.PatientID = string(tokens{1});
                else
                    app.PatientID = "unknown";
                end

                % ---- 3. Buscar ese ID en participants.tsv ----
                if ~isempty(app.ParticipantsTable)

                    ids = string(app.ParticipantsTable.Participant_ID);
                    idx = find(ids == app.PatientID, 1);   % posición de sub-01

                    if ~isempty(idx)
                        row = app.ParticipantsTable(idx, :);

                        app.PatientGender    = string(row.Gender);
                        app.PatientAge       = row.Age;
                        app.PatientParalysis = string(row.ParalysisSide);
                        app.PatientNIHSS = row.NIHSS;
                        app.PatientMBI   = row.MBI;
                        app.PatientmRS   = row.mRS;

                    else
                        % No se encontró el ID en la tabla
                        app.PatientGender    = "unknown";
                        app.PatientAge       = NaN;
                        app.PatientParalysis = "unknown";
                        app.PatientNIHSS     = NaN;
                        app.PatientMBI       = NaN;
                        app.PatientmRS       = NaN;
                    end
                else
                    % La tabla está vacía
                    app.PatientGender    = "unknown";
                    app.PatientAge       = NaN;
                    app.PatientParalysis = "unknown";
                    app.PatientNIHSS     = NaN;
                    app.PatientMBI       = NaN;
                    app.PatientmRS       = NaN;
                end

                % Actualizar TextArea con los datos del paciente
                UpdatePatientInfo(app);


                sigL = vertcat(data.HEOL{:});
                sigR = vertcat(data.HEOR{:});
                EOG = sigL-sigR;
                fs = 500;
                t = (0:length(EOG)-1)/fs;

                plot(app.Mainplot,t,sigL)

                segLengths = [2, 4, 2] * fs;  % [2s, 4s, 2s] in samples
                totalBlockLength = sum(segLengths);  % 8*fs samples
                nBlocks = floor(length(sigL)/totalBlockLength);
                block(nBlocks) = struct('sig', [], 't', []);

                for k = 1:nBlocks
                    startIdx = (k-1)*totalBlockLength + 1;

                    % Extract the full block
                    blockSig = sigL(startIdx : startIdx + totalBlockLength - 1);
                    blockT   = t(startIdx : startIdx + totalBlockLength - 1);

                    % Split the block into the 3 subarrays
                    idx1 = 1 : segLengths(1);
                    idx2 = segLengths(1)+1 : sum(segLengths(1:2));
                    idx3 = sum(segLengths(1:2))+1 : totalBlockLength;

                    block(k).sig = { blockSig(idx1), blockSig(idx2), blockSig(idx3) };
                    block(k).t   = { blockT(idx1), blockT(idx2), blockT(idx3) };
                end
                app.blocks = block;
            end
        end

        % Button pushed function: DataButton
        function ChangeWindow(app, event)
            pos = app.Mainmenu.Position;
            app.Mainmenu.Visible = 'off';
            app.Subplots.Position = pos;
            app.Subplots.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);

            nBlocks = length(app.blocks);
            dropdownItems = arrayfun(@(k) ['Prueba' num2str(k)], 1:nBlocks, 'UniformOutput', false);
            app.PruebasDropDown.Items = dropdownItems;
            app.PruebasDropDown.Value = dropdownItems{1}; % poner la primera como seleccionada
            ChoosePrueba(app, event)
        end

        % Value changed function: PruebasDropDown
        function ChoosePrueba(app, event)
            selected = app.PruebasDropDown.Value;

            % Obtener número de prueba seleccionada
            n = str2double(extractAfter(selected, 'Prueba'));
            app.index = n;
            app.Introplot = plot(app.intro, app.blocks(n).t{1}, app.blocks(n).sig{1});
            app.Testplot = plot(app.test, app.blocks(n).t{2}, app.blocks(n).sig{2});
            app.Restplot = plot(app.rest, app.blocks(n).t{3}, app.blocks(n).sig{3});
        end

        % Button pushed function: IntroButton
        function ExpandedIntro(app, event)
            ExpandedView(app,0)
        end

        % Button pushed function: TestButton
        function ExpandedTest(app, event)
            ExpandedView(app,1)
        end

        % Button pushed function: RestButton
        function ExpandedRest(app, event)
            ExpandedView(app,2)
        end

        % Button pushed function: GoBackButtonAnalisis
        function Goback(app, event)
            pos = app.AnalisisWindow.Position;
            app.AnalisisWindow.Visible = 'off';
            app.Subplots.Position = pos;
            app.Subplots.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);
        end

        % Button pushed function: RellenarButton
        function RellenarButtonPushed(app, event)
            % ---- 1. Datos de ejemplo ----
            lado      = {'Izquierda'; 'Derecha'};
            numMov    = [8; 15];       % número de movimientos
            latencia  = [0.45; 0.38];  % segundos (ejemplo)
            aciertos  = [70; 92];      % porcentaje (ejemplo)

            % ---- 2. Crear la tabla de MATLAB ----
            T = table(lado, numMov, latencia, aciertos, ...
                'VariableNames', {'Lado','NumMovimientos','LatenciaMedia','PorcentajeAciertos'});

            % ---- 3. Mostrarla en la UITable de la app ----
            app.UITableResultados.Data = T;
            % ---- Ajuste automático del tamaño de la tabla ----
            alturaFila = 25;
            numFilas = height(T);

            anchuraCol = 100;
            numColumnas = width(T);

            margenAncho = 40;
            margenAlto = 40;

            nuevoAncho = numColumnas * anchuraCol + margenAncho;
            nuevoAlto  = numFilas * alturaFila + margenAlto;

            pos = app.UITableResultados.Position;
            app.UITableResultados.Position = [pos(1), pos(2), nuevoAncho, nuevoAlto];

            % ---- 4. Colorear celdas según NumMovimientos ----
            % Borrar estilos anteriores
            removeStyle(app.UITableResultados);

            % Definir estilos
            rojo  = uistyle('BackgroundColor',[1 0.8 0.8]);   % rojo suave
            verde = uistyle('BackgroundColor',[0.8 1 0.8]);   % verde suave

            % Recorrer filas de la tabla
            for i = 1:height(T)
                if T.NumMovimientos(i) < 10
                    % Poner en rojo la celda de NumMovimientos (columna 2)
                    addStyle(app.UITableResultados, rojo, 'cell', [i 2]);
                else
                    % Poner en verde la celda de NumMovimientos
                    addStyle(app.UITableResultados, verde, 'cell', [i 2]);

                end
            end
        end

        % Button pushed function: AnalisisButton
        function AnalisisButtonPushed(app, event)

            pos = app.Subplots.Position;
            app.Subplots.Visible = 'off';
            app.AnalisisWindow.Position = pos;
            app.AnalisisWindow.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);

            UpdatePatientInfo(app);
        end

        function UpdatePatientInfo(app)

            % Valores por defecto si están vacíos
            if isempty(app.PatientID)
                app.PatientID = "unknown";
            end
            if isempty(app.PatientGender)
                app.PatientGender = "unknown";
            end
            if isempty(app.PatientAge)
                app.PatientAge = NaN;
            end
            if isempty(app.PatientParalysis)
                app.PatientParalysis = "unknown";
            end
            if isempty(app.PatientNIHSS)
                app.PatientNIHSS = NaN;
            end
            if isempty(app.PatientMBI)
                app.PatientMBI = NaN;
            end
            if isempty(app.PatientmRS)
                app.PatientmRS = NaN;
            end

            % Creamos un ARRAY DE STRINGS (no celdas)
            info = [
                "Paciente: "       + string(app.PatientID)
                "Gender: "         + string(app.PatientGender)
                "Age: "            + string(app.PatientAge)
                "Paralysis side: " + string(app.PatientParalysis)
                "NIHSS: "          + string(app.PatientNIHSS)
                "MBI: "            + string(app.PatientMBI)
                "mRS: "            + string(app.PatientmRS)
                ];

            % Lo escribimos en el Text Area
            app.PatientInfoLabel.Text = info;
        end

        % Button pushed function: GoBackButtonEyes
        function GoBackButtonEyesPushed(app, event)
            pos = app.EyesWindow.Position;
            app.EyesWindow.Visible = 'off';
            app.Subplots.Position = pos;
            app.Subplots.Visible = 'on';
            app.UIFigure.Position(3:4) = pos(3:4);
        end

        function ApplyTheme(app)
            colorGrafito = [43 43 43] / 255;     % fondo general
            colorBoton   = [167 37 69] / 255;    % carmesí
            colorBlanco  = [248 248 255] / 255;  % ghostwhite (fondo de ejes)

            app.UIFigure.Color = colorGrafito;

            app.Mainmenu.BackgroundColor      = colorGrafito;
            app.Subplots.BackgroundColor      = colorGrafito;
            app.AnalisisWindow.BackgroundColor = colorGrafito;
            app.EyesWindow.BackgroundColor    = colorGrafito;

            %botones
            botones = [
                app.CargardatosButton
                app.DataButton
                app.IntroButton
                app.TestButton
                app.RestButton
                app.AnalisisButton
                app.RellenarButton
                app.GoBackButtonEyes
                app.GoBackButtonAnalisis
                ];

            for b = botones'
                b.BackgroundColor = colorBoton;
                b.FontColor = [1 1 1]; % blanco
                b.FontWeight = 'bold';
            end

            axesList = [
                app.Mainplot
                app.intro
                app.test
                app.rest
                app.ExpandedPlot
                ];

            for ax = axesList'
                ax.Color = colorBlanco;
                ax.XGrid = 'off';
                ax.YGrid = 'off';
            end
            colorTexto = [248 248 255]/255;   % blanco “ghostwhite”

            % Labels
            app.ANLISISPACIENTELabel.FontColor  = colorTexto;
            app.PruebasDropDownLabel.FontColor = colorTexto;
            app.Label.FontColor                = colorTexto;
            app.DATOSDELPACIENTELabel.FontColor = colorTexto;
            app.FATIGALabel.FontColor = colorTexto;
            app.GoBackButtonAnalisis.FontColor = [1 1 1];  % ya lo ponemos en el bucle de botones
            app.GoBackButtonEyes.FontColor     = [1 1 1];

            % --- TextAreas estilo oscuro ---
            app.PatientInfoLabel.BackgroundColor = colorGrafito;
            app.PatientInfoLabel.FontColor = colorTexto;


            app.FatigaLabel.BackgroundColor = colorGrafito;
            app.FatigaLabel.FontColor = colorTexto;


            % --- Texto de los ejes y títulos en blanco, líneas del eje en negro ---
            axesList = [
                app.Mainplot
                app.intro
                app.test
                app.rest
                app.ExpandedPlot
                ];

            for ax = axesList'

                % Fondo blanco
                ax.Color = colorBlanco;

                % --- Título de la gráfica en blanco (si quieres blanco como antes) ---
                ax.Title.Color = colorTexto;

                % --- Líneas del eje en NEGRO ---
                ax.XColor = colorTexto;
                ax.YColor = colorTexto;

                % --- NÚMEROS de los ejes (ticks) EN BLANCO ---
                if isprop(ax.XAxis, 'TickLabelColor')
                    ax.XAxis.TickLabelColor = colorTexto; % blanco
                    ax.YAxis.TickLabelColor = colorTexto; % blanco
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Mainmenu = uipanel(app.UIFigure);
            app.Mainplot = uiaxes(app.Mainmenu);
            title(app.Mainplot, 'Signal View')
            xlabel(app.Mainplot, 'X')
            ylabel(app.Mainplot, 'Y')
            zlabel(app.Mainplot, 'Z')
            app.CargardatosButton = uibutton(app.Mainmenu, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.Label = uilabel(app.Mainmenu);
            app.Label.Text = '';
            app.DataButton = uibutton(app.Mainmenu, 'push');
            app.DataButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeWindow, true);
            app.DataButton.Text = 'Data';
            app.Subplots = uipanel(app.UIFigure);
            app.intro = uiaxes(app.Subplots);
            title(app.intro, 'Introduction (0-2s)')
            xlabel(app.intro, 'X')
            ylabel(app.intro, 'Y')
            zlabel(app.intro, 'Z')
            app.test = uiaxes(app.Subplots);
            title(app.test, 'Motor Imagery Test (2-6s)')
            xlabel(app.test, 'X')
            ylabel(app.test, 'Y')
            zlabel(app.test, 'Z')
            app.rest = uiaxes(app.Subplots);
            title(app.rest, 'Rest (6-8s)')
            xlabel(app.rest, 'X')
            ylabel(app.rest, 'Y')
            zlabel(app.rest, 'Z')
            app.PruebasDropDownLabel = uilabel(app.Subplots);
            app.PruebasDropDownLabel.Text = 'Pruebas';
            app.PruebasDropDown = uidropdown(app.Subplots);
            app.PruebasDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePrueba, true);
            app.AnalisisButton = uibutton(app.Subplots, 'push');
            app.AnalisisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisisButtonPushed, true);
            app.AnalisisButton.Text = 'Analisis';
            app.IntroButton = uibutton(app.Subplots, 'push');
            app.IntroButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedIntro, true);
            app.IntroButton.Text = 'Intro+';
            app.TestButton = uibutton(app.Subplots, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedTest, true);
            app.TestButton.Text = 'Test+';
            app.RestButton = uibutton(app.Subplots, 'push');
            app.RestButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandedRest, true);
            app.RestButton.Text = 'Rest+';
            app.AnalisisWindow = uipanel(app.UIFigure);
            app.GoBackButtonAnalisis = uibutton(app.AnalisisWindow, 'push');
            app.GoBackButtonAnalisis.ButtonPushedFcn = createCallbackFcn(app, @Goback, true);
            app.GoBackButtonAnalisis.Text = 'Go Back';
            app.UITableResultados = uitable(app.AnalisisWindow);
            app.UITableResultados.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultados.RowName = {};
            app.RellenarButton = uibutton(app.AnalisisWindow, 'push');
            app.RellenarButton.ButtonPushedFcn = createCallbackFcn(app, @RellenarButtonPushed, true);
            app.RellenarButton.Text = 'Rellenar Tabla';
            app.ANLISISPACIENTELabel = uilabel(app.AnalisisWindow);
            app.ANLISISPACIENTELabel.Text = 'ANÁLISIS PACIENTE';
            app.Image = uiimage(app.AnalisisWindow);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'CUERPO (1).png');
            app.DATOSDELPACIENTELabel = uilabel(app.AnalisisWindow);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.FATIGALabel = uilabel(app.AnalisisWindow);
            app.FATIGALabel.Text = 'FATIGA';
            app.PatientInfoLabel = uilabel(app.AnalisisWindow);
            app.FatigaLabel = uilabel(app.AnalisisWindow);
            app.FatigaLabel.Text = 'Label2';
            app.EyesWindow = uipanel(app.UIFigure);
            app.ExpandedPlot = uiaxes(app.EyesWindow);
            title(app.ExpandedPlot, 'Signal View')
            xlabel(app.ExpandedPlot, 'X')
            ylabel(app.ExpandedPlot, 'Y')
            zlabel(app.ExpandedPlot, 'Z')
            app.GoBackButtonEyes = uibutton(app.EyesWindow, 'push');
            app.GoBackButtonEyes.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonEyesPushed, true);
            app.GoBackButtonEyes.Text = 'Go Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sepa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end